classdef ansto_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        figure1                  matlab.ui.Figure
        text9                    matlab.ui.control.Label
        text11                   matlab.ui.control.Label
        text13                   matlab.ui.control.Label
        pushbutton5              matlab.ui.control.Button
        text14                   matlab.ui.control.Label
        pushbutton8              matlab.ui.control.Button
        text15                   matlab.ui.control.Label
        text16                   matlab.ui.control.Label
        edit16                   matlab.ui.control.EditField
        text17                   matlab.ui.control.Label
        edit17                   matlab.ui.control.EditField
        text18                   matlab.ui.control.Label
        edit18                   matlab.ui.control.EditField
        text19                   matlab.ui.control.Label
        edit19                   matlab.ui.control.EditField
        pushbutton9              matlab.ui.control.Button
        pushbutton10             matlab.ui.control.Button
        pushbutton11             matlab.ui.control.Button
        pushbutton12             matlab.ui.control.Button
        text20                   matlab.ui.control.Label
        text21                   matlab.ui.control.Label
        text22                   matlab.ui.control.Label
        text24                   matlab.ui.control.Label
        MagnetTypeDropDownLabel  matlab.ui.control.Label
        MagnetTypeDropDown       matlab.ui.control.DropDown
        VariablesPanel           matlab.ui.container.Panel
        edit15_2                 matlab.ui.control.EditField
        edit15_3                 matlab.ui.control.EditField
        edit15_4                 matlab.ui.control.EditField
        edit15_5                 matlab.ui.control.EditField
        edit15_6                 matlab.ui.control.EditField
        edit15_7                 matlab.ui.control.EditField
        edit15_8                 matlab.ui.control.EditField
        edit15_9                 matlab.ui.control.EditField
        edit15_10                matlab.ui.control.EditField
        edit15_11                matlab.ui.control.EditField
        edit15_12                matlab.ui.control.EditField
        edit15_16                matlab.ui.control.EditField
        edit15_17                matlab.ui.control.EditField
        edit15_18                matlab.ui.control.EditField
        edit15_19                matlab.ui.control.EditField
        edit15_20                matlab.ui.control.EditField
        edit15_21                matlab.ui.control.EditField
        edit15_22                matlab.ui.control.EditField
        edit15_23                matlab.ui.control.EditField
        edit15_24                matlab.ui.control.EditField
        edit15_25                matlab.ui.control.EditField
        edit15_26                matlab.ui.control.EditField
        edit15_27                matlab.ui.control.EditField
        edit15_28                matlab.ui.control.EditField
        ObjectivesPanel          matlab.ui.container.Panel
        edit11                   matlab.ui.control.EditField
        togglebutton1            matlab.ui.control.StateButton
        togglebutton4            matlab.ui.control.StateButton
        edit12                   matlab.ui.control.EditField
        togglebutton2            matlab.ui.control.StateButton
        togglebutton5            matlab.ui.control.StateButton
        edit14                   matlab.ui.control.EditField
        togglebutton3            matlab.ui.control.StateButton
        togglebutton6            matlab.ui.control.StateButton
        EditField                matlab.ui.control.NumericEditField
        EditField_3              matlab.ui.control.NumericEditField
        EditField_2              matlab.ui.control.NumericEditField
        text20_2                 matlab.ui.control.Label
        text21_2                 matlab.ui.control.Label
        text21_3                 matlab.ui.control.Label
        text11_3                 matlab.ui.control.Label
        text11_4                 matlab.ui.control.Label
    end

    properties (Access = public)
         nobj = 0;
         nvar = 0;
         initial_variable_set_points = [];
         vrange = [];
         p0 = [];
         x0 = [];
         g_data = [];
         g_cnt = 0;
    end
    methods (Access = private)

        function edit10_CreateFcn(app, hObject, eventdata, handles)
            % hObject    handle to edit10 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    empty - handles not created until after all CreateFcns called
            
            % Hint: edit controls usually have a white background on Windows.
            %       See ISPC and COMPUTER.
            if ispc && isequal(get(hObject,'BackgroundColor'), get(0,'defaultUicontrolBackgroundColor'))
                set(hObject,'BackgroundColor','white');
            end
        end
        
        function edit1_CreateFcn(app, hObject, eventdata, handles)
            % hObject    handle to edit1 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    empty - handles not created until after all CreateFcns called
            
            % Hint: edit controls usually have a white background on Windows.
            %       See ISPC and COMPUTER.
            if ispc && isequal(get(hObject,'BackgroundColor'), get(0,'defaultUicontrolBackgroundColor'))
                set(hObject,'BackgroundColor','white');
            end
        end
        
        function edit2_CreateFcn(app, hObject, eventdata, handles)
            % hObject    handle to edit2 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    empty - handles not created until after all CreateFcns called
            
            % Hint: edit controls usually have a white background on Windows.
            %       See ISPC and COMPUTER.
            if ispc && isequal(get(hObject,'BackgroundColor'), get(0,'defaultUicontrolBackgroundColor'))
                set(hObject,'BackgroundColor','white');
            end
        end
        
        function edit4_CreateFcn(app, hObject, eventdata, handles)
            % hObject    handle to edit4 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    empty - handles not created until after all CreateFcns called
            
            % Hint: edit controls usually have a white background on Windows.
            %       See ISPC and COMPUTER.
            if ispc && isequal(get(hObject,'BackgroundColor'), get(0,'defaultUicontrolBackgroundColor'))
                set(hObject,'BackgroundColor','white');
            end
        end
        
        function edit5_Callback(app, hObject, eventdata, handles)
            % hObject    handle to edit5 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: get(hObject,'String') returns contents of edit5 as text
            %        str2double(get(hObject,'String')) returns contents of edit5 as a double
            
            value = get(hObject,'String');
            set(handles.edit6,'String' ,value);
        end
        
        function edit5_CreateFcn(app, hObject, eventdata, handles)
            % hObject    handle to edit5 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    empty - handles not created until after all CreateFcns called
            
            % Hint: edit controls usually have a white background on Windows.
            %       See ISPC and COMPUTER.
            if ispc && isequal(get(hObject,'BackgroundColor'), get(0,'defaultUicontrolBackgroundColor'))
                set(hObject,'BackgroundColor','white');
            end
        end
        
        function edit6_CreateFcn(app, hObject, eventdata, handles)
            % hObject    handle to edit6 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    empty - handles not created until after all CreateFcns called
            
            % Hint: edit controls usually have a white background on Windows.
            %       See ISPC and COMPUTER.
            if ispc && isequal(get(hObject,'BackgroundColor'), get(0,'defaultUicontrolBackgroundColor'))
                set(hObject,'BackgroundColor','white');
            end
        end
        
        function edit7_CreateFcn(app, hObject, eventdata, handles)
            % hObject    handle to edit7 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    empty - handles not created until after all CreateFcns called
            
            % Hint: edit controls usually have a white background on Windows.
            %       See ISPC and COMPUTER.
            if ispc && isequal(get(hObject,'BackgroundColor'), get(0,'defaultUicontrolBackgroundColor'))
                set(hObject,'BackgroundColor','white');
            end
        end
        
        function edit8_CreateFcn(app, hObject, eventdata, handles)
            % hObject    handle to edit8 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    empty - handles not created until after all CreateFcns called
            
            % Hint: edit controls usually have a white background on Windows.
            %       See ISPC and COMPUTER.
            if ispc && isequal(get(hObject,'BackgroundColor'), get(0,'defaultUicontrolBackgroundColor'))
                set(hObject,'BackgroundColor','white');
            end
        end
        
        function edit9_CreateFcn(app, hObject, eventdata, handles)
            % hObject    handle to edit9 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    empty - handles not created until after all CreateFcns called
            
            % Hint: edit controls usually have a white background on Windows.
            %       See ISPC and COMPUTER.
            if ispc && isequal(get(hObject,'BackgroundColor'), get(0,'defaultUicontrolBackgroundColor'))
                set(hObject,'BackgroundColor','white');
            end
        end


        function listbox2_CreateFcn(app, hObject, eventdata, handles)
            % hObject    handle to listbox2 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    empty - handles not created until after all CreateFcns called
            
            % Hint: listbox controls usually have a white background on Windows.
            %       See ISPC and COMPUTER.
            if ispc && isequal(get(hObject,'BackgroundColor'), get(0,'defaultUicontrolBackgroundColor'))
                set(hObject,'BackgroundColor','white');
            end
        end
        
        function listbox3_CreateFcn(app, hObject, eventdata, handles)
            % hObject    handle to listbox3 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    empty - handles not created until after all CreateFcns called
            
            % Hint: listbox controls usually have a white background on Windows.
            %       See ISPC and COMPUTER.
            if ispc && isequal(get(hObject,'BackgroundColor'), get(0,'defaultUicontrolBackgroundColor'))
                set(hObject,'BackgroundColor','white');
            end
        end
        
        function pushbutton1_Callback(app, hObject, eventdata, handles)
            % hObject    handle to pushbutton1 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            answer = func_obj_kickerbump_july19(app, 2);
            set(handles.edit1,'string' ,answer);
        end
        function obj = generic_objective(app,x)
            % Do not this section - it sets the parameter boundary %control and scaling.
            
            global vrange
            Nvar = size(vrange, 1);
            
            if size(x,1)==1
                x = x';
            end
            p = vrange(:,1) + (vrange(:,2) - vrange(:,1)).*x;
            
            if min(x)<0 || max(x)>1
                dxlim = 0;
                for ii=1:Nvar
                    if x(ii)<0
                        dxlim = dxlim + abs(x(ii));
                    elseif x(ii)>1
                        dxlim = dxlim + abs(x(ii)-1);
                    end
                end
                
                obj = NaN; %-5 + dxlim^2;
                return;
            end
            
            %% Objective function definition
            % asum = set objective function initially to 0
            % e.g asum = 0;
            % Over the number of variables, update the initial objfunction -
            % formula and update for every parameter p0
            % e.g for i = 1 : Nvar - 1
            %    asum = asum - 10*exp(-0.2*sqrt((p(i))^2 + (p(i + 1))^2));
            %end
            % Add random number to simulate noise.
            %asum = asum + randn*0.001;
            %obj = asum;
            
            % For optimization of skew quads experimentally:
            % Set Skew Quads
            %setsp('SKQ', p);
            %setsp('SR13CPS10:CURRENT_SP', p(1));
            %setsp('SR13CPS11:CURRENT_SP', p(2));
            %setsp('SR14CPS10:CURRENT_SP', p(3));
            %setsp('SR14CPS11:CURRENT_SP', p(4));
            %pause(2); % pause for 1 seconds
            %variables
%             setsp(app.edit15_2.Value, p(1));
%             setsp(app.edit15_6.Value, p(2));
%             setsp(app.edit15_25.Value, p(3));
%             setsp(app.edit15_26.Value, p(4));
%             setsp(app.edit15_27.Value, p(5));
%             setsp(app.edit15_28.Value, p(6));
            [r,c]=find(ismember(p,Inf));
            p(r,:)=[];
            %setsp('SR14KM01:PULSE_CENTRE_OF_MASS_MONITOR', p(5));
            %setsp('SR01KM01:PULSE_CENTRE_OF_MASS_MONITOR', p(6));
            %setsp('SR01KM02:PULSE_CENTRE_OF_MASS_MONITOR', p(7));
            %setsp('SR02KM01:PULSE_CENTRE_OF_MASS_MONITOR'), p(8);
            
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            %   Kicker Voltages
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            %Getting values for objective values
            
            rad_dose = getpv(app.edit11.Value);
            obj2 = getpv(app.edit12.Value);
            obj3 = getpv(app.edit14.Value);
           
            %Assigning min and max
            obj1 = -rad_dose;
            if app.togglebutton5 == true
                obj2 = -obj2;
            end
            if app.togglebutton6 == true
                obj3 = -obj3;
            end
            obj = app.EditField.Value*obj1 + app.EditField_3.Value*obj2 + app.EditField_2.Value*obj3;


            %% Save Data
            global g_data g_cnt
            g_cnt = g_cnt+1;
            %g_data(g_cnt,:) = [p',obj]; %
            
            % Save data
            %g_data(g_cnt, :) = [p', obj];
            % Save data including vertical beam size
            g_data(g_cnt, :) = [p', obj1, obj2, obj3];
            [g_cnt, p', obj1, obj2, obj3]
            % Print Solution[g_cnt, p',obj]
        end
        
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function ansto_OpeningFcn(app, varargin)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app); %#ok<ASGLU>
            
            % This function has no output args, see OutputFcn.
            % hObject    handle to figure
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            % varargin   command line arguments to ansto (see VARARGIN)
            
            % Choose default command line output for ansto
            handles.output = hObject;
            
            % Update handles structure
            guidata(hObject, handles);
        end

        % Value changed function: MagnetTypeDropDown
        function MagnetTypeDropDownValueChanged(app, event)
            value = app.MagnetTypeDropDown.Value;
            if strncmpi(value,'1',1)
                app.nvar = 3;
                app.edit15_26.Visible = 'off';
                app.edit15_16.Visible = 'off';
                app.edit15_17.Visible = 'off';
                app.edit15_18.Visible = 'off';
                app.edit15_27.Visible = 'off';
                app.edit15_19.Visible = 'off';
                app.edit15_20.Visible = 'off';
                app.edit15_21.Visible = 'off';
                app.edit15_22.Visible = 'off';
                app.edit15_23.Visible = 'off';
                app.edit15_24.Visible = 'off';
                app.edit15_28.Visible = 'off';
                app.edit11.Value = 'IS00VRM03:DOSE_RATE_MONITOR';
                app.edit12.Value = 'IS00IE01:LIN_BR_EXT_EFFICIENCY';
                app.edit14.Value = 'IS00BR01FCT01:VOLT_SECS_MONITOR';
                app.edit15_2.Value = 'PS-B-A-1-1:CURRENT_SP';
                app.edit15_3.Value = num2str(getpv('PS-B-A-1-1:CURRENT_SP.LOPR'));
                app.edit15_4.Value = num2str(getpv('PS-B-A-1-1:CURRENT_SP.HOPR'));
                app.edit15_5.Value = num2str(getpv('PS-B-A-1-1:CURRENT_SP'));
                app.edit15_6.Value = 'PsrS-B-A-1-2:CURRENT_SP';
                app.edit15_7.Value = num2str(getpv('PS-B-A-1-2:CURRENT_SP.LOPR'));
                app.edit15_8.Value = num2str(getpv('PS-B-A-1-2:CURRENT_SP.HOPR'));
                app.edit15_9.Value = num2str(getpv('PS-B-A-1-2:CURRENT_SP'));
                app.edit15_25.Value = 'PS-B-B-1:CURRENT_SP';
                app.edit15_10.Value = num2str(getpv('PS-B-B-1:CURRENT_SP.LOPR'));
                app.edit15_11.Value = num2str(getpv('PS-B-B-1:CURRENT_SP.HOPR'));
                app.edit15_12.Value = num2str(getpv('PS-B-B-1:CURRENT_SP'));
            elseif strncmpi(value,'2',1)
                app.nvar = 6;
                app.edit15_26.Visible = 'on';
                app.edit15_16.Visible = 'on';
                app.edit15_17.Visible = 'on';
                app.edit15_18.Visible = 'on';
                app.edit15_27.Visible = 'on';
                app.edit15_19.Visible = 'on';
                app.edit15_20.Visible = 'on';
                app.edit15_21.Visible = 'on';
                app.edit15_22.Visible = 'on';
                app.edit15_23.Visible = 'on';
                app.edit15_24.Visible = 'on';
                app.edit15_28.Visible = 'on';
                app.edit11.Value = 'SR13VRM01:DOSE_RATE_MONITOR';
                app.edit12.Value = 'SR00IE01:INJECTION_EFFICIENCY_MONITOR';
                app.edit14.Value = 'SR00IE02:INJECTION_EFFICIENCY_MONITOR';
                app.edit15_2.Value = 'PS-BA-3-1:CURRENT_SP';
                app.edit15_3.Value = num2str(getpv('PS-BA-3-1:CURRENT_SP.LOPR'));
                app.edit15_4.Value = num2str(getpv('PS-BA-3-1:CURRENT_SP.HOPR'));
                app.edit15_5.Value = num2str(getpv('PS-BA-3-1:CURRENT_SP'));
                app.edit15_6.Value = 'PS-BA-3-2:CURRENT_SP';
                app.edit15_7.Value = num2str(getpv('PS-BA-3-2:CURRENT_SP.LOPR'));
                app.edit15_8.Value = num2str(getpv('PS-BA-3-2:CURRENT_SP.HOPR'));
                app.edit15_9.Value = num2str(getpv('PS-BA-3-2:CURRENT_SP'));
                app.edit15_25.Value = 'PS-BA-3-3:CURRENT_SP';
                app.edit15_10.Value = num2str(getpv('PS-BA-3-3:CURRENT_SP.LOPR'));
                app.edit15_11.Value = num2str(getpv('PS-BA-3-3:CURRENT_SP.HOPR'));
                app.edit15_12.Value = num2str(getpv('PS-BA-3-3:CURRENT_SP'));
                app.edit15_26.Value = 'PS-BA-3-4:CURRENT_SP';
                app.edit15_16.Value = num2str(getpv('PS-BA-3-4:CURRENT_SP.LOPR'));
                app.edit15_17.Value = num2str(getpv('PS-BA-3-4:CURRENT_SP.HOPR'));
                app.edit15_18.Value = num2str(getpv('PS-BA-3-4:CURRENT_SP'));
                app.edit15_27.Value = 'PS-SEP-A-3:CURRENT_SP';
                app.edit15_19.Value = num2str(getpv('PS-SEP-A-3:CURRENT_SP.LOPR'));
                app.edit15_20.Value = num2str(getpv('PS-SEP-A-3:CURRENT_SP.HOPR'));
                app.edit15_21.Value = num2str(getpv('PS-SEP-A-3:CURRENT_SP'));
                app.edit15_28.Value = 'PS-SEP-B-3:CURRENT_SP';
                app.edit15_22.Value = num2str(getpv('PS-SEP-B-3:CURRENT_SP.LOPR'));
                app.edit15_23.Value = num2str(getpv('PS-SEP-B-3:CURRENT_SP.HOPR'));
                app.edit15_24.Value = num2str(getpv('PS-SEP-B-3:CURRENT_SP'));
             elseif strncmpi(value,'3',1)
                app.nvar = 4;
                app.edit15_27.Visible = 'off';
                app.edit15_19.Visible = 'off';
                app.edit15_20.Visible = 'off';
                app.edit15_21.Visible = 'off';
                app.edit15_22.Visible = 'off';
                app.edit15_23.Visible = 'off';
                app.edit15_24.Visible = 'off';
                app.edit15_28.Visible = 'off';
                app.edit11.Value = 'SR14VRM01:DOSE_RATE_MONITOR';    
                app.edit12.Value = 'SR10BM02IMG01:Y_SIZE_MONITOR'; 
                app.edit14.Value = 'SR00IE02:INJECTION_EFFICIENCY_MONITOR'; 
                app.edit15_2.Value = 'SR14KPS01:VOLTAGE_SP';
                app.edit15_3.Value = num2str(getpv('SR14KPS01:VOLTAGE_SP.LOPR'));
                app.edit15_4.Value = num2str(getpv('SR14KPS01:VOLTAGE_SP.HOPR'));
                app.edit15_5.Value = num2str(getpv('SR14KPS01:VOLTAGE_SP'));
                app.edit15_6.Value = 'SR01KPS01:VOLTAGE_SP';
                app.edit15_7.Value = num2str(getpv('SR01KPS01:VOLTAGE_SP.LOPR'));
                app.edit15_8.Value = num2str(getpv('SR01KPS01:VOLTAGE_SP.HOPR'));
                app.edit15_9.Value = num2str(getpv('SR01KPS01:VOLTAGE_SP'));
                app.edit15_25.Value = 'SR01KPS02:VOLTAGE_SP';
                app.edit15_10.Value = num2str(getpv('SR01KPS02:VOLTAGE_SP.LOPR'));
                app.edit15_11.Value = num2str(getpv('SR01KPS02:VOLTAGE_SP.HOPR'));
                app.edit15_12.Value = num2str(getpv('SR01KPS02:VOLTAGE_SP'));
                app.edit15_26.Value = 'SR02KPS01:VOLTAGE_SP';
                app.edit15_16.Value = num2str(getpv('SR02KPS01:VOLTAGE_SP.LOPR'));
                app.edit15_17.Value = num2str(getpv('SR02KPS01:VOLTAGE_SP.HOPR'));
                app.edit15_18.Value = num2str(getpv('SR02KPS01:VOLTAGE_SP'));
                
            end
            %app.edit11ValueChanged();
            
        end

        % Value changed function: edit11
        function edit11ValueChanged(app, event)
           
            value = app.edit11.Value;
            
           if strcmp(value,'Edit Text') == 0
                
                app.nobj = 1;
           end
        end

        % Button pushed function: pushbutton5
        function pushbutton5ButtonPushed(app, event)
            
            %app.p0 = [getsp(app.edit15_2.Value);getsp(app.edit15_6.Value);getsp(app.edit15_25.Value);getsp(app.edit15_26.Value);getsp(app.edit15_27.Value);getsp(app.edit15_28.Value)];
            app.p0 = [86.180;87.050;87.033];
            app.vrange = [str2double(app.edit15_3.Value), str2double(app.edit15_4.Value); 
                str2double(app.edit15_7.Value), str2double(app.edit15_8.Value); 
                str2double(app.edit15_10.Value), str2double(app.edit15_11.Value); 
                str2double(app.edit15_16.Value), str2double(app.edit15_17.Value); 
                str2double(app.edit15_19.Value), str2double(app.edit15_20.Value); 
                str2double(app.edit15_22.Value), str2double(app.edit15_23.Value)];
            app.vrange = app.vrange(1:app.nvar,:);
            app.x0 = (app.p0-app.vrange(:,1))./(app.vrange(:,2)-app.vrange(:,1));
            [r,c]=find(ismember(app.x0,Inf));
            app.x0(r,:)=[];
            app.text11_3.Text = 'Variables and objectives are saved';
            
        end

        % Value changed function: edit12
        function edit12ValueChanged(app, event)
            value = app.edit12.Value;


        end

        % Value changed function: edit14
        function edit14ValueChanged(app, event)
            value = app.edit14.Value;
           
        end

        % Value changed function: edit17
        function edit17ValueChanged(app, event)
            value = app.edit17.Value;
            
        end

        % Button pushed function: pushbutton8
        function pushbutton8ButtonPushed(app, event)
            dfname = appendtimestamp('diary');
            diary(dfname);
            app.text11_4.Text = 'File is stored in same directory as RCDS code, timestamp will have today date';
        end

        % Button pushed function: pushbutton9
        function pushbutton9ButtonPushed(app, event)
            dmat = eye(app.nvar);
            [x1,f1,nf]=powellmain(@app.generic_objective,app.x0,str2double(app.edit17.Value),dmat,str2double(app.edit16.Value),str2double(app.edit18.Value),'plot',str2double(app.edit19.Value));
            N = size(app.g_data,1);
           
            plot(1:N, app.g_data(:,app.nvar+1),'.')
        end

        % Button pushed function: pushbutton11
        function pushbutton11ButtonPushed(app, event)
            
        [data_1,xm,fm] = process_scandata_v2(app.g_data,app.Nvar,app.vrange,'plot');
        [xm(:)', fm]
        end

        % Value changed function: EditField
        function EditFieldValueChanged(app, event)
            value = app.EditField.Value;
            if value == 0
                app.nobj = app.nobj - 1;
            end 
        end

        % Value changed function: EditField_3
        function EditField_3ValueChanged(app, event)
            value = app.EditField_3.Value;
            if value == 0
                app.nobj = app.nobj - 1;
            end 
        end

        % Value changed function: EditField_2
        function EditField_2ValueChanged(app, event)
            value = app.EditField_2.Value;
            if value == 0
                app.nobj = app.nobj - 1;
            end 
        end

        % Button pushed function: pushbutton10
        function pushbutton10ButtonPushed(app, event)
            closereq;
        end

        % Button pushed function: pushbutton12
        function pushbutton12ButtonPushed(app, event)
            app.MagnetTypeDropDownValueChanged();
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.figure1 = uifigure('Visible', 'off');
            app.figure1.Name = 'ansto';
            app.figure1.Tag = 'figure1';
            app.text9 = uilabel(app.figure1);
            app.text9.Tag = 'text9';
            app.text9.Text = 'RCDS GUI';
            app.text11 = uilabel(app.figure1);
            app.text11.Tag = 'text11';
            app.text11.Text = {'Step 1 - Choose Variables and Objectives'; ''};
            app.text13 = uilabel(app.figure1);
            app.text13.Tag = 'text13';
            app.text13.Text = 'Step 2 - Confirm ';
            app.pushbutton5 = uibutton(app.figure1, 'push');
            app.pushbutton5.ButtonPushedFcn = createCallbackFcn(app, @pushbutton5ButtonPushed, true);
            app.pushbutton5.Tag = 'pushbutton5';
            app.pushbutton5.Text = {'Confirm input variables and objectives'; ''};
            app.text14 = uilabel(app.figure1);
            app.text14.Tag = 'text14';
            app.text14.Text = {'Step 3 - Click to store data'; ''};
            app.pushbutton8 = uibutton(app.figure1, 'push');
            app.pushbutton8.ButtonPushedFcn = createCallbackFcn(app, @pushbutton8ButtonPushed, true);
            app.pushbutton8.Tag = 'pushbutton8';
            app.pushbutton8.Text = {'Confirm to store data in file'; ''};
            app.text15 = uilabel(app.figure1);
            app.text15.Tag = 'text15';
            app.text15.Text = 'Step 4 - Enter algorithm variables';
            app.text16 = uilabel(app.figure1);
            app.text16.Tag = 'text16';
            app.text16.Text = 'Tolerance';
            app.edit16 = uieditfield(app.figure1, 'text');
            app.edit16.Tag = 'edit16';
            app.edit16.Value = '0';
            app.text17 = uilabel(app.figure1);
            app.text17.Tag = 'text17';
            app.text17.Text = 'Step Size';
            app.edit17 = uieditfield(app.figure1, 'text');
            app.edit17.ValueChangedFcn = createCallbackFcn(app, @edit17ValueChanged, true);
            app.edit17.Tag = 'edit17';
            app.edit17.Value = '0.01';
            app.text18 = uilabel(app.figure1);
            app.text18.Tag = 'text18';
            app.text18.Text = 'Max. Iterations';
            app.edit18 = uieditfield(app.figure1, 'text');
            app.edit18.Tag = 'edit18';
            app.edit18.Value = '100';
            app.text19 = uilabel(app.figure1);
            app.text19.Tag = 'text19';
            app.text19.Text = 'Max. Evaluations';
            app.edit19 = uieditfield(app.figure1, 'text');
            app.edit19.Tag = 'edit19';
            app.edit19.Value = '1000';
            app.pushbutton9 = uibutton(app.figure1, 'push');
            app.pushbutton9.ButtonPushedFcn = createCallbackFcn(app, @pushbutton9ButtonPushed, true);
            app.pushbutton9.Tag = 'pushbutton9';
            app.pushbutton9.Text = 'RUN FOR PLOTS';
            app.pushbutton10 = uibutton(app.figure1, 'push');
            app.pushbutton10.ButtonPushedFcn = createCallbackFcn(app, @pushbutton10ButtonPushed, true);
            app.pushbutton10.Tag = 'pushbutton10';
            app.pushbutton10.Text = 'STOP';
            app.pushbutton11 = uibutton(app.figure1, 'push');
            app.pushbutton11.ButtonPushedFcn = createCallbackFcn(app, @pushbutton11ButtonPushed, true);
            app.pushbutton11.Tag = 'pushbutton11';
            app.pushbutton11.Text = 'RUN FOR MORE PLOTS';
            app.pushbutton12 = uibutton(app.figure1, 'push');
            app.pushbutton12.ButtonPushedFcn = createCallbackFcn(app, @pushbutton12ButtonPushed, true);
            app.pushbutton12.Tag = 'pushbutton12';
            app.pushbutton12.Text = 'RESET';
            app.text20 = uilabel(app.figure1);
            app.text20.Tag = 'text20';
            app.text20.Text = 'Name';
            app.text21 = uilabel(app.figure1);
            app.text21.Tag = 'text21';
            app.text21.Text = 'Low';
            app.text22 = uilabel(app.figure1);
            app.text22.Tag = 'text22';
            app.text22.Text = 'High';
            app.text24 = uilabel(app.figure1);
            app.text24.Tag = 'text24';
            app.text24.Text = 'Value';
            app.MagnetTypeDropDownLabel = uilabel(app.figure1);
            app.MagnetTypeDropDownLabel.Text = 'Magnet Type';
            app.MagnetTypeDropDown = uidropdown(app.figure1);
            app.MagnetTypeDropDown.Items = {'LTB', 'Kickers', 'BTS', '', ''};
            app.MagnetTypeDropDown.ItemsData = {'1', '2', '3', '', ''};
            app.MagnetTypeDropDown.ValueChangedFcn = createCallbackFcn(app, @MagnetTypeDropDownValueChanged, true);
            app.MagnetTypeDropDown.Value = '3';
            app.VariablesPanel = uipanel(app.figure1);
            app.VariablesPanel.Title = 'Variables';
            app.edit15_2 = uieditfield(app.VariablesPanel, 'text');
            app.edit15_2.Tag = 'edit15';
            app.edit15_2.Value = 'Edit Text';
            app.edit15_3 = uieditfield(app.VariablesPanel, 'text');
            app.edit15_3.Tag = 'edit15';
            app.edit15_3.Value = '0';
            app.edit15_4 = uieditfield(app.VariablesPanel, 'text');
            app.edit15_4.Tag = 'edit15';
            app.edit15_4.Value = '0';
            app.edit15_5 = uieditfield(app.VariablesPanel, 'text');
            app.edit15_5.Tag = 'edit15';
            app.edit15_5.Value = '0';
            app.edit15_6 = uieditfield(app.VariablesPanel, 'text');
            app.edit15_6.Tag = 'edit15';
            app.edit15_6.Value = 'Edit Text';
            app.edit15_7 = uieditfield(app.VariablesPanel, 'text');
            app.edit15_7.Tag = 'edit15';
            app.edit15_7.Value = '0';
            app.edit15_8 = uieditfield(app.VariablesPanel, 'text');
            app.edit15_8.Tag = 'edit15';
            app.edit15_8.Value = '0';
            app.edit15_9 = uieditfield(app.VariablesPanel, 'text');
            app.edit15_9.Tag = 'edit15';
            app.edit15_9.Value = '0';
            app.edit15_10 = uieditfield(app.VariablesPanel, 'text');
            app.edit15_10.Tag = 'edit15';
            app.edit15_10.Value = '0';
            app.edit15_11 = uieditfield(app.VariablesPanel, 'text');
            app.edit15_11.Tag = 'edit15';
            app.edit15_11.Value = '0';
            app.edit15_12 = uieditfield(app.VariablesPanel, 'text');
            app.edit15_12.Tag = 'edit15';
            app.edit15_12.Value = '0';
            app.edit15_16 = uieditfield(app.VariablesPanel, 'text');
            app.edit15_16.Tag = 'edit15';
            app.edit15_16.Value = '0';
            app.edit15_17 = uieditfield(app.VariablesPanel, 'text');
            app.edit15_17.Tag = 'edit15';
            app.edit15_17.Value = '0';
            app.edit15_18 = uieditfield(app.VariablesPanel, 'text');
            app.edit15_18.Tag = 'edit15';
            app.edit15_18.Value = '0';
            app.edit15_19 = uieditfield(app.VariablesPanel, 'text');
            app.edit15_19.Tag = 'edit15';
            app.edit15_19.Value = '0';
            app.edit15_20 = uieditfield(app.VariablesPanel, 'text');
            app.edit15_20.Tag = 'edit15';
            app.edit15_20.Value = '0';
            app.edit15_21 = uieditfield(app.VariablesPanel, 'text');
            app.edit15_21.Tag = 'edit15';
            app.edit15_21.Value = '0';
            app.edit15_22 = uieditfield(app.VariablesPanel, 'text');
            app.edit15_22.Tag = 'edit15';
            app.edit15_22.Value = '0';
            app.edit15_23 = uieditfield(app.VariablesPanel, 'text');
            app.edit15_23.Tag = 'edit15';
            app.edit15_23.Value = '0';
            app.edit15_24 = uieditfield(app.VariablesPanel, 'text');
            app.edit15_24.Tag = 'edit15';
            app.edit15_24.Value = '0';
            app.edit15_25 = uieditfield(app.VariablesPanel, 'text');
            app.edit15_25.Tag = 'edit15';
            app.edit15_25.Value = 'Edit Text';
            app.edit15_26 = uieditfield(app.VariablesPanel, 'text');
            app.edit15_26.Tag = 'edit15';
            app.edit15_26.Value = 'Edit Text';
            app.edit15_27 = uieditfield(app.VariablesPanel, 'text');
            app.edit15_27.Tag = 'edit15';
            app.edit15_27.Value = 'Edit Text';
            app.edit15_28 = uieditfield(app.VariablesPanel, 'text');
            app.edit15_28.Tag = 'edit15';
            app.edit15_28.Value = 'Edit Text';
            app.ObjectivesPanel = uipanel(app.figure1);
            app.ObjectivesPanel.Title = 'Objectives';
            app.edit11 = uieditfield(app.ObjectivesPanel, 'text');
            app.edit11.ValueChangedFcn = createCallbackFcn(app, @edit11ValueChanged, true);
            app.edit11.Tag = 'edit11';
            app.edit11.Value = 'Edit Text';
            app.togglebutton1 = uibutton(app.ObjectivesPanel, 'state');
            app.togglebutton1.Tag = 'togglebutton1';
            app.togglebutton1.Text = {'Max'; ''};
            app.togglebutton4 = uibutton(app.ObjectivesPanel, 'state');
            app.togglebutton4.Tag = 'togglebutton4';
            app.togglebutton4.Text = {'Min'; ''};
            app.togglebutton4.Value = true;
            app.edit12 = uieditfield(app.ObjectivesPanel, 'text');
            app.edit12.ValueChangedFcn = createCallbackFcn(app, @edit12ValueChanged, true);
            app.edit12.Tag = 'edit12';
            app.edit12.Value = 'Edit Text';
            app.togglebutton2 = uibutton(app.ObjectivesPanel, 'state');
            app.togglebutton2.Tag = 'togglebutton2';
            app.togglebutton2.Text = {'Max'; ''};
            app.togglebutton5 = uibutton(app.ObjectivesPanel, 'state');
            app.togglebutton5.Tag = 'togglebutton5';
            app.togglebutton5.Text = {'Min'; ''};
            app.edit14 = uieditfield(app.ObjectivesPanel, 'text');
            app.edit14.ValueChangedFcn = createCallbackFcn(app, @edit14ValueChanged, true);
            app.edit14.Tag = 'edit14';
            app.edit14.Value = 'Edit Text';
            app.togglebutton3 = uibutton(app.ObjectivesPanel, 'state');
            app.togglebutton3.Tag = 'togglebutton3';
            app.togglebutton3.Text = {'Max'; ''};
            app.togglebutton6 = uibutton(app.ObjectivesPanel, 'state');
            app.togglebutton6.Tag = 'togglebutton6';
            app.togglebutton6.Text = {'Min'; ''};
            app.EditField = uieditfield(app.ObjectivesPanel, 'numeric');
            app.EditField.ValueChangedFcn = createCallbackFcn(app, @EditFieldValueChanged, true);
            app.EditField.Value = 1;
            app.EditField_3 = uieditfield(app.ObjectivesPanel, 'numeric');
            app.EditField_3.ValueChangedFcn = createCallbackFcn(app, @EditField_3ValueChanged, true);
            app.EditField_3.Value = 1;
            app.EditField_2 = uieditfield(app.ObjectivesPanel, 'numeric');
            app.EditField_2.ValueChangedFcn = createCallbackFcn(app, @EditField_2ValueChanged, true);
            app.EditField_2.Value = 1;
            app.text20_2 = uilabel(app.figure1);
            app.text20_2.Tag = 'text20';
            app.text20_2.Text = 'Name';
            app.text21_2 = uilabel(app.figure1);
            app.text21_2.Tag = 'text21';
            app.text21_2.Text = 'Max. or Min.';
            app.text21_3 = uilabel(app.figure1);
            app.text21_3.Tag = 'text21';
            app.text21_3.Text = 'Scale';
            app.text11_3 = uilabel(app.figure1);
            app.text11_3.Tag = 'text11';
            app.text11_3.Text = {''; ''};
            app.text11_4 = uilabel(app.figure1);
            app.text11_4.Tag = 'text11';
            app.text11_4.Text = {''; ''};
            % Show the figure after all components are created
            app.figure1.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ansto_App(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.figure1)

            % Execute the startup function
            runStartupFcn(app, @(app)ansto_OpeningFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.figure1)
        end
    end
end